classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NBAStatsPredictor           matlab.ui.Figure
        NumberofGamestoshowSpinnerLabel  matlab.ui.control.Label
        NumberofGamestoshowSpinner  matlab.ui.control.Spinner
        UIAxes                      matlab.ui.control.UIAxes
        PlotofDropDownLabel         matlab.ui.control.Label
        PlotofDropDown              matlab.ui.control.DropDown
        TeamDropDownLabel           matlab.ui.control.Label
        TeamDropDown                matlab.ui.control.DropDown
        PreviousStatsTable          matlab.ui.control.Table
        PlayerDropDownLabel         matlab.ui.control.Label
        PlayerDropDown              matlab.ui.control.DropDown
        StatisticsfrompreviousgamesLabel  matlab.ui.control.Label
        PredictedStatsTable         matlab.ui.control.Table
        PredictedStatsLabel         matlab.ui.control.Label
        PlayerInfoTable             matlab.ui.control.Table
        PlayerInfoLabel             matlab.ui.control.Label
        CalulateAgainButton         matlab.ui.control.Button
        StatusLabel1                matlab.ui.control.Label
        StatusLabel                 matlab.ui.control.Label
        ShowFittingButton           matlab.ui.control.Button
        FittingAccuracyRsquaredparameterLabel  matlab.ui.control.Label
        ChoosefunctiontypeDropDownLabel  matlab.ui.control.Label
        ChoosefunctiontypeDropDown  matlab.ui.control.DropDown
        ErrorTable                  matlab.ui.control.Table
        NoteRsquaredlessthan0meansthatitisbettertousemeanvalueLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        playersStruct=[];
        teamsStruct=[];
        season=2018;
        statsToPlot;
        isDefault;
        functionModel;
        fittings={};
    end
    
    methods (Access = private)
        
        function results = checkInternetConnection(app)
            [~,b]=dos('ping -n 1 www.google.com');
                n=strfind(b,'Lost');
                results=b(n+7);     
        end
        function makeTeamDropdown(app)
            app.teamsStruct=getTeamsStructs('teams.json');
                app.playersStruct=getPlayersStructs('players.json');
                teamNames=ReturnAllTeamsNames(app.teamsStruct);
                teamIds=ReturnAllTeamsIds(app.teamsStruct);
                app.TeamDropDown.Items=['Choose a team' teamNames];
                app.TeamDropDown.ItemsData=[1 teamIds];  
        end
        
        
        function hideElements(app)
            app.PreviousStatsTable.Data = {};
            app.PredictedStatsTable.Data = {};
            app.ErrorTable.Data={};
            app.PlayerInfoTable.Visible=0;
            app.PlayerInfoLabel.Visible=0;
            app.PlotofDropDown.Visible=0;
            app.PlotofDropDownLabel.Visible=0;
            app.ShowFittingButton.Visible=0;
            app.CalulateAgainButton.Visible=0;
            cla(app.UIAxes);
            app.UIAxes.Visible=0;
        end
        
        function [teamId,playerId]=makeInfoTable(app,value)
            chosenPlayer=getChosenPlayer(value,app.playersStruct);
            teamId=str2num(chosenPlayer.teamId);
            playerId=chosenPlayer.personId;
            chosenPlayer=rmfield(chosenPlayer,'teamId');
            chosenPlayer=rmfield(chosenPlayer,'personId');
            chosenPlayer=rmfield(chosenPlayer,'teamSitesOnly');
            app.PlayerInfoLabel.Visible=1;
            app.PlayerInfoTable.Visible=1;
            drawnow;
            c=struct2cell(chosenPlayer);
            app.PlayerInfoTable.Data=c';
        end
        
        function showElements(app)
            app.CalulateAgainButton.Visible=1;
            app.ShowFittingButton.Visible=1;
            app.PlotofDropDown.Visible=1;
            app.PlotofDropDownLabel.Visible=1;
            app.PlotofDropDown.ItemsData=[1,2,3,4,5];
            app.PlotofDropDown.Value=1;
            app.UIAxes.Visible=1;    
        end
        function makeErrorTable(app)
            pointsR=app.fittings{1, 1}.Rsquared.Ordinary;
            offRebsR=app.fittings{1, 2}.Rsquared.Ordinary;
            defRebR=app.fittings{1, 3}.Rsquared.Ordinary;
            assistsR=app.fittings{1, 4}.Rsquared.Ordinary;
            stealsR=app.fittings{1, 5}.Rsquared.Ordinary;
            turnoversR=app.fittings{1, 6}.Rsquared.Ordinary;
            blocksR=app.fittings{1, 7}.Rsquared.Ordinary;
            errorStruct=[pointsR,offRebsR,defRebR,assistsR,stealsR,turnoversR,blocksR];
            errorStruct=round(errorStruct.*1000);
            errorStruct=errorStruct./1000;
            app.ErrorTable.Data=errorStruct;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            year=app.season;
            app.StatusLabel.Visible=1;
            app.isDefault=1;
            app.functionModel="Linear";
            app.StatusLabel.Text='Updating Files';
            drawnow;
            n1=checkInternetConnection(app); 
            if(n1=='0')
                updateFiles(year);
                app.StatusLabel.Text='Parsing Files';
                drawnow;
                makeTeamDropdown(app);
                app.StatusLabel.Text='Ready';
                drawnow;
            else
                app.StatusLabel.Text='Cannot connect. Close app and retry.';
                drawnow;
            end
            
            
        end

        % Value changed function: TeamDropDown
        function TeamDropDownValueChanged(app, event)
            hideElements(app)
            app.StatusLabel.Text='Getting Players List';
            drawnow;
            value = app.TeamDropDown.Value;
            teamPlayers=getPlayersOfTheTeam(value,app.playersStruct);
            namesToDisp=ReturnPlayersNames(teamPlayers);
            playersIds=ReturnPlayersIds(teamPlayers);
            app.PlayerDropDown.Visible=1;
            app.PlayerDropDownLabel.Visible=1;
            if isempty(namesToDisp)
                app.PlayerDropDown.Items="Choose a player";
                app.PlayerDropDown.ItemsData='1';
            else
                app.PlayerDropDown.Items=['Choose a player' namesToDisp];
                app.PlayerDropDown.ItemsData=[1 playersIds];
            end
            app.StatusLabel.Text='Ready';
            drawnow;
        end

        % Value changed function: PlayerDropDown
        function PlayerDropDownValueChanged(app, event)
            hideElements(app)
            app.StatusLabel.Text='Getting Player Info';
            drawnow;
            %Player info table
            value = app.PlayerDropDown.Value;
            if value==1
                app.StatusLabel.Text='Ready';
            else
                [teamId,playerId]=makeInfoTable(app,value);
                %Previous stas table
                numOfGames=app.NumberofGamestoshowSpinner.Value;
                app.StatusLabel.Text='Getting Player Stats';
                drawnow;
                n1=checkInternetConnection(app);
                if(n1=='0')
                    [fullStats,numOfGames]=getFullStatsFromLastGames(app.season,changeTeamId2TeamUrl(teamId,app.teamsStruct), numOfGames);
                    app.NumberofGamestoshowSpinner.Value=numOfGames;
                    [lastGamesStats,lastGamesStatsToCalculate] =getStatsForPlayerFromSingleGame(fullStats, playerId, numOfGames);
                    lastGamesStats = changeStringsToNumbersInStats(lastGamesStats);
                    c=struct2cell(lastGamesStats');
                    app.PreviousStatsTable.Data=c';
                    
                    %Prediced stats Table
                    app.StatusLabel.Text='Computing predicted Stats';
                    drawnow;
                    lastGamesStatsToCalculate = changeStringsToNumbersInStats(lastGamesStatsToCalculate);
                    [app.fittings, predictedStats]=predictStats(lastGamesStatsToCalculate,app.functionModel);
                    c=struct2cell(predictedStats');
                    app.PredictedStatsTable.Data=c';
                    
                    makeErrorTable(app);
                    
                    %Plot
                    app.statsToPlot=lastGamesStatsToCalculate;
                    points=vertcat(lastGamesStatsToCalculate.points);
                    showElements(app);
                    plot(app.UIAxes,linspace(1,sum(size(lastGamesStatsToCalculate))-1,sum(size(lastGamesStatsToCalculate))-1),points,'--xk');
                    ylim(app.UIAxes,[0 (max(points)+2)]);
                    app.StatusLabel.Text='Ready';
                    drawnow;
                else
                    app.StatusLabel.Text='Cannot connect. Close app and retry.';
                    drawnow;
                end
                
            end
        end

        % Button pushed function: CalulateAgainButton
        function CalulateAgainButtonPushed(app, event)
            %Hide previous data
            hideElements(app)
            app.StatusLabel.Text='Getting Player Info';
            drawnow;
            
            %Player info table
            value = app.PlayerDropDown.Value;
            if value==1
                app.StatusLabel.Text='Ready';
            else
                [teamId,playerId]=makeInfoTable(app,value);
                
                %Previous stas table
                numOfGames=app.NumberofGamestoshowSpinner.Value;
                year=app.season;
                app.StatusLabel.Text='Getting Player Stats';
                drawnow;
                n1=checkInternetConnection(app);
                if(n1=='0')
                    [fullStats,numOfGames]=getFullStatsFromLastGames(year,changeTeamId2TeamUrl(teamId,app.teamsStruct), numOfGames);
                    app.NumberofGamestoshowSpinner.Value=numOfGames;
                    [lastGamesStats,lastGamesStatsToCalculate] =getStatsForPlayerFromSingleGame(fullStats, playerId, numOfGames);
                    lastGamesStats = changeStringsToNumbersInStats(lastGamesStats);
                    c=struct2cell(lastGamesStats');
                    app.PreviousStatsTable.Data=c';
                    
                    %Prediced stats Table
                    app.StatusLabel.Text='Computing predicted Stats';
                    drawnow;
                    lastGamesStatsToCalculate = changeStringsToNumbersInStats(lastGamesStatsToCalculate);
                    [app.fittings, predictedStats]=predictStats(lastGamesStatsToCalculate,app.functionModel);
                    c=struct2cell(predictedStats');
                    app.PredictedStatsTable.Data=c';
                    
                    makeErrorTable(app);
                    
                    %Plot
                    app.statsToPlot=lastGamesStatsToCalculate;
                    points=vertcat(lastGamesStatsToCalculate.points);
                    showElements(app);
                    plot(app.UIAxes,linspace(1,sum(size(lastGamesStatsToCalculate))-1,sum(size(lastGamesStatsToCalculate))-1),points,'--xk');
                    ylim(app.UIAxes,[0 (max(points)+2)]);
                    app.StatusLabel.Text='Ready';
                    drawnow;
                else
                    app.StatusLabel.Text='Cannot connect. Close app and retry.';
                    drawnow;
                end
                
            end
        end

        % Button pushed function: ShowFittingButton
        function ShowFittingButtonPushed(app, event)
            app.StatusLabel.Text='Generating Plots';
            drawnow;
            plotPredictedStats(app.fittings);
            app.StatusLabel.Text='Ready';
            drawnow;
        end

        % Value changed function: PlotofDropDown
        function PlotofDropDownValueChanged(app, event)
            value = app.PlotofDropDown.Value;
            switch value
                case 2
                    assists=vertcat(app.statsToPlot.assists);
                    plot(app.UIAxes,linspace(1,sum(size(app.statsToPlot))-1,sum(size(app.statsToPlot))-1),assists,'--xb');
                    ylim(app.UIAxes,[0 (max(assists)+2)])
                    drawnow;
                case 3
                    totReb=vertcat(app.statsToPlot.totReb);
                    plot(app.UIAxes,linspace(1,sum(size(app.statsToPlot))-1,sum(size(app.statsToPlot))-1),totReb,'--xg');
                    ylim(app.UIAxes,[0 (max(totReb)+2)])
                    drawnow; 
                case 4
                    steals=vertcat(app.statsToPlot.steals);
                     plot(app.UIAxes,linspace(1,sum(size(app.statsToPlot))-1,sum(size(app.statsToPlot))-1),steals,'--xm');
                     ylim(app.UIAxes,[0 (max(steals)+2)])
                    drawnow;       
                case 5
                    turnovers=vertcat(app.statsToPlot.turnovers);
                    plot(app.UIAxes,linspace(1,sum(size(app.statsToPlot))-1,sum(size(app.statsToPlot))-1),turnovers,'--xr');
                    ylim(app.UIAxes,[0 (max(turnovers)+2)])
                    drawnow;
                otherwise
                    points=vertcat(app.statsToPlot.points);
                    plot(app.UIAxes,linspace(1,sum(size(app.statsToPlot))-1,sum(size(app.statsToPlot))-1),points,'--xk');
                    ylim(app.UIAxes,[0 (max(points)+2)])
                    drawnow;
            end
        end

        % Value changed function: ChoosefunctiontypeDropDown
        function ChoosefunctiontypeDropDownValueChanged(app, event)
            value = app.ChoosefunctiontypeDropDown.Value;
            value=str2num(value);
            switch value
                case 1
                    app.functionModel="Linear";
                case 2
                    app.functionModel="Polynomial";
                case 3
                    app.functionModel="Logarithmic";
                case 4
                    app.functionModel="Exponencial";
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NBAStatsPredictor = uifigure;
            app.NBAStatsPredictor.Name = 'NBAStatsPredictor';
            app.NumberofGamestoshowSpinnerLabel = uilabel(app.NBAStatsPredictor);
            app.NumberofGamestoshowSpinnerLabel.Text = 'Number of Games to show';
            app.NumberofGamestoshowSpinner = uispinner(app.NBAStatsPredictor);
            app.NumberofGamestoshowSpinner.Limits = [5 110];
            app.NumberofGamestoshowSpinner.Value = 10;
            app.UIAxes = uiaxes(app.NBAStatsPredictor);
            title(app.UIAxes, '(DNP omitted)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.PlotofDropDownLabel = uilabel(app.NBAStatsPredictor);
            app.PlotofDropDownLabel.Visible = 'off';
            app.PlotofDropDownLabel.Text = 'Plot of:';
            app.PlotofDropDown = uidropdown(app.NBAStatsPredictor);
            app.PlotofDropDown.Items = {'Points', 'Assists', 'Rebounds', 'Steals', 'Turnovers'};
            app.PlotofDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotofDropDownValueChanged, true);
            app.PlotofDropDown.Visible = 'off';
            app.PlotofDropDown.Value = 'Points';
            app.TeamDropDownLabel = uilabel(app.NBAStatsPredictor);
            app.TeamDropDownLabel.Text = 'Team';
            app.TeamDropDown = uidropdown(app.NBAStatsPredictor);
            app.TeamDropDown.Items = {};
            app.TeamDropDown.Editable = 'on';
            app.TeamDropDown.ValueChangedFcn = createCallbackFcn(app, @TeamDropDownValueChanged, true);
            app.TeamDropDown.Value = {};
            app.PreviousStatsTable = uitable(app.NBAStatsPredictor);
            app.PreviousStatsTable.ColumnName = {'Points'; 'Minutes'; 'Off Reb'; 'Def Reb'; 'Tot Reb'; 'Assists'; 'PF'; 'Steals'; 'Turnovers'; 'Blocks'; '+-'; 'Date'};
            app.PreviousStatsTable.RowName = {};
            app.PreviousStatsTable.ColumnEditable = false;
            app.PlayerDropDownLabel = uilabel(app.NBAStatsPredictor);
            app.PlayerDropDownLabel.Visible = 'off';
            app.PlayerDropDownLabel.Text = 'Player';
            app.PlayerDropDown = uidropdown(app.NBAStatsPredictor);
            app.PlayerDropDown.Items = {};
            app.PlayerDropDown.Editable = 'on';
            app.PlayerDropDown.ValueChangedFcn = createCallbackFcn(app, @PlayerDropDownValueChanged, true);
            app.PlayerDropDown.Visible = 'off';
            app.PlayerDropDown.Value = {};
            app.StatisticsfrompreviousgamesLabel = uilabel(app.NBAStatsPredictor);
            app.StatisticsfrompreviousgamesLabel.Text = 'Statistics from previous games';
            app.PredictedStatsTable = uitable(app.NBAStatsPredictor);
            app.PredictedStatsTable.ColumnName = {'Points'; 'Off Reb'; 'Def Reb'; 'Tot Reb'; 'Assists'; 'PF'; 'Steals'; 'Turnovers'; 'Blocks'};
            app.PredictedStatsTable.RowName = {};
            app.PredictedStatsLabel = uilabel(app.NBAStatsPredictor);
            app.PredictedStatsLabel.Text = 'Predicted Stats';
            app.PlayerInfoTable = uitable(app.NBAStatsPredictor);
            app.PlayerInfoTable.ColumnName = {'First name'; 'Last Name'; 'Number'; 'Position'; 'Height [m]'; 'Weight [kg]'; 'Date Of Birth'; 'Country'};
            app.PlayerInfoTable.RowName = {};
            app.PlayerInfoTable.Visible = 'off';
            app.PlayerInfoLabel = uilabel(app.NBAStatsPredictor);
            app.PlayerInfoLabel.Visible = 'off';
            app.PlayerInfoLabel.Text = 'Player Info';
            app.CalulateAgainButton = uibutton(app.NBAStatsPredictor, 'push');
            app.CalulateAgainButton.ButtonPushedFcn = createCallbackFcn(app, @CalulateAgainButtonPushed, true);
            app.CalulateAgainButton.Visible = 'off';
            app.CalulateAgainButton.Text = 'Calulate Again';
            app.StatusLabel1 = uilabel(app.NBAStatsPredictor);
            app.StatusLabel1.Text = 'Status:';
            app.StatusLabel = uilabel(app.NBAStatsPredictor);
            app.StatusLabel.Text = 'Ready';
            app.ShowFittingButton = uibutton(app.NBAStatsPredictor, 'push');
            app.ShowFittingButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFittingButtonPushed, true);
            app.ShowFittingButton.Visible = 'off';
            app.ShowFittingButton.Text = 'Show Fitting';
            app.FittingAccuracyRsquaredparameterLabel = uilabel(app.NBAStatsPredictor);
            app.FittingAccuracyRsquaredparameterLabel.Text = 'Fitting Accuracy(R-squared parameter):';
            app.ChoosefunctiontypeDropDownLabel = uilabel(app.NBAStatsPredictor);
            app.ChoosefunctiontypeDropDownLabel.Text = 'Choose function type';
            app.ChoosefunctiontypeDropDown = uidropdown(app.NBAStatsPredictor);
            app.ChoosefunctiontypeDropDown.Items = {'Linear', 'Polynomial', 'Logarithmic', 'Exponential'};
            app.ChoosefunctiontypeDropDown.ItemsData = {'1', '2', '3', '4', ''};
            app.ChoosefunctiontypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosefunctiontypeDropDownValueChanged, true);
            app.ChoosefunctiontypeDropDown.Value = '1';
            app.ErrorTable = uitable(app.NBAStatsPredictor);
            app.ErrorTable.ColumnName = {'Points'; 'Off Reb'; 'Def Reb'; 'Assists'; 'Steals'; 'Turnovers'; 'Blocks'};
            app.ErrorTable.RowName = {};
            app.NoteRsquaredlessthan0meansthatitisbettertousemeanvalueLabel = uilabel(app.NBAStatsPredictor);
            app.NoteRsquaredlessthan0meansthatitisbettertousemeanvalueLabel.Text = 'Note: R-squared less than 0 means that it is better to use mean value';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NBAStatsPredictor)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NBAStatsPredictor)
        end
    end
end